classdef MotionControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LogBox                        matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        ManualControlTab              matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        STOPALLButton                 matlab.ui.control.Button
        TxPolAxisPanel                matlab.ui.container.Panel
        AUTROLLAxisPanel              matlab.ui.container.Panel
        AZAxisPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        FaultLamp                     matlab.ui.control.Lamp
        FaultLampLabel                matlab.ui.control.Label
        MovingLamp                    matlab.ui.control.Lamp
        MovingLampLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        MovetoButton                  matlab.ui.control.Button
        TargetPositionEditField       matlab.ui.control.NumericEditField
        TargetPositionEditFieldLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ConfigTab                     matlab.ui.container.Tab
        GPIBAddressDropDown           matlab.ui.control.DropDown
        GPIBAddressDropDownLabel      matlab.ui.control.Label
        SavelogButton                 matlab.ui.control.Button
        ClearlogButton                matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        SerialportDropDown            matlab.ui.control.DropDown
        SerialportDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Reference back to the main app
    end
    
    methods (Access = private)
        function refreshSerialPortList(app)
            app.SerialportDropDown.Items = seriallist();
        end
    end
    
    methods (Access = public)
        function Log(app, msg) 
            if strcmp(app.LogBox.Value,'')
                app.LogBox.Value = msg;
            else
                app.LogBox.Value = [app.LogBox.Value(:)', {msg}];
            end

            app.LogBox.scroll('bottom');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            
            app.refreshSerialPortList();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.MotionControllerMenu.Enable = 'on';
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshSerialPortList();
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            value = app.SerialportDropDown.Value;
            app.Log(sprintf('Serial port changed to %s', value));
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.LogBox.Value = '';
        end

        % Button pushed function: SavelogButton
        function SavelogButtonPushed(app, event)
            msgbox('TODO');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.GridLayout2 = uigridlayout(app.ManualControlTab);
            app.AZAxisPanel = uipanel(app.GridLayout2);
            app.AZAxisPanel.Title = 'AZ Axis';
            app.GridLayout3 = uigridlayout(app.AZAxisPanel);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '+ 0.00';
            app.Panel = uipanel(app.GridLayout3);
            app.TargetPositionEditFieldLabel = uilabel(app.Panel);
            app.TargetPositionEditFieldLabel.Text = 'Target Position';
            app.TargetPositionEditField = uieditfield(app.Panel, 'numeric');
            app.MovetoButton = uibutton(app.Panel, 'push');
            app.MovetoButton.Text = 'Move to';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.Text = '+ 5';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.Text = '- 5';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.MovingLampLabel = uilabel(app.GridLayout4);
            app.MovingLampLabel.Text = 'Moving';
            app.MovingLamp = uilamp(app.GridLayout4);
            app.FaultLampLabel = uilabel(app.GridLayout4);
            app.FaultLampLabel.Text = 'Fault';
            app.FaultLamp = uilamp(app.GridLayout4);
            app.AUTROLLAxisPanel = uipanel(app.GridLayout2);
            app.AUTROLLAxisPanel.Title = 'AUTROLL Axis';
            app.TxPolAxisPanel = uipanel(app.GridLayout2);
            app.TxPolAxisPanel.Title = 'Tx Pol Axis';
            app.STOPALLButton = uibutton(app.GridLayout2, 'push');
            app.STOPALLButton.Text = 'STOP ALL';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.SerialportDropDownLabel = uilabel(app.ConfigTab);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConfigTab);
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.SerialportDropDown.Placeholder = 'Pick';
            app.RefreshButton = uibutton(app.ConfigTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ClearlogButton = uibutton(app.ConfigTab, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.SavelogButton = uibutton(app.ConfigTab, 'push');
            app.SavelogButton.ButtonPushedFcn = createCallbackFcn(app, @SavelogButtonPushed, true);
            app.SavelogButton.Text = 'Save log...';
            app.GPIBAddressDropDownLabel = uilabel(app.ConfigTab);
            app.GPIBAddressDropDownLabel.Text = 'GPIB Address';
            app.GPIBAddressDropDown = uidropdown(app.ConfigTab);
            app.GPIBAddressDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.GPIBAddressDropDown.Placeholder = 'Pick';
            app.GPIBAddressDropDown.Value = '0';
            app.LogBox = uitextarea(app.GridLayout);
            app.LogBox.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionControllerApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end